classdef GroundControlSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
        NextButton          matlab.ui.control.Button
        PrevButton          matlab.ui.control.Button
        ZoomSlider          matlab.ui.control.Slider
        ZoomSliderLabel     matlab.ui.control.Label
        RemoveGCPButton     matlab.ui.control.Button
        SelectGCPsButton    matlab.ui.control.Button
        Label2              matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        SelectRegionButton  matlab.ui.control.Button
        ForwardButton       matlab.ui.control.Button
        GPS_desc_label      matlab.ui.control.Label
        BackButton          matlab.ui.control.Button
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    methods (Access = private)
        function updateHighlight(app)
            mask = strcmp(app.GPSpoints{:,2}, app.setnames{app.currentIndex});
            app.highlightPlot.LatitudeData = app.GPSpoints.Latitude(mask);
            app.highlightPlot.LongitudeData = app.GPSpoints.Longitude(mask);
            app.GPS_desc_label.Text = app.setnames{app.currentIndex};
            app.updateImage();
        end
    
        function updateImage(app)
            mask = strcmp(app.GPSpoints{:,2}, app.setnames{app.currentIndex});
            imgfile = app.GPSpoints.FileIDX(mask);
            imgfile = imgfile(1);
            if strcmp(imgfile, "")
                cla(app.UIAxes2);
            else
                img = imread(imgfile);
                imshow(img, 'Parent', app.UIAxes2);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Back button pushed
            app.GPS_desc_label.Text="test";
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % Forward button pushed
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                     % Confirm close (optional)
            selection = uiconfirm(app.UIFigure, ...
                'Are you sure you want to close the app?', ...
                'Close Confirmation', ...
                'Options', {'Yes', 'No'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
    
            if strcmp(selection, 'Yes')
                delete(app); % Closes the app
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.BackButton = uibutton(app.GridLayout2, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.GPS_desc_label = uilabel(app.GridLayout2);
            app.ForwardButton = uibutton(app.GridLayout2, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.SelectRegionButton = uibutton(app.GridLayout2, 'push');
            app.SelectRegionButton.Text = 'Select Region';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Label2 = uilabel(app.GridLayout3);
            app.Label2.Text = 'Label2';
            app.SelectGCPsButton = uibutton(app.GridLayout3, 'push');
            app.SelectGCPsButton.Text = 'Select GCPs';
            app.RemoveGCPButton = uibutton(app.GridLayout3, 'push');
            app.RemoveGCPButton.Text = 'Remove GCP';
            app.ZoomSliderLabel = uilabel(app.GridLayout3);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.GridLayout3);
            app.PrevButton = uibutton(app.GridLayout3, 'push');
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.GridLayout3, 'push');
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroundControlSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end